classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectButton            matlab.ui.control.Button
        ControllerDropDown       matlab.ui.control.DropDown
        ControllerDropDownLabel  matlab.ui.control.Label
        RunModeDropDown          matlab.ui.control.DropDown
        RunModeDropDownLabel     matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        LineAxes                 matlab.ui.control.UIAxes
        ErrorAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cfg
        appState
        tuneDB

        net
        runLog
        frameBuf

        ctrlReg struct
        steerCtrlState


        tmr
        t0 uint64 = uint64(0);
        stopFlag logical = true;
                
    end


    methods (Access = private)
        
        % Initialize app
        function startup(app)
        
            % ===== Resolve path to mlab/ =====
            appFile = which("monitor");          % path to monitor.mlapp
            appDir  = fileparts(appFile);        % .../mlab/app
            mlabDir = fileparts(appDir);         % .../mlab
        
            addpath(genpath(mlabDir));
            rehash;                              % OK trong App Designer
        
            % ===== Debug (có thể giữ) =====
            disp("monitor.mlapp at: " + string(appFile));
            disp("mlabDir = " + string(mlabDir));
            disp("exist capstone.io.Network = " + string(exist("capstone.io.Network","class")));
        
            % ===== Init app data =====
            app.cfg      = capstone.types.makeCfgDefault();           % hoặc capstone.types nếu bạn đã đổi tên folder
            app.appState = capstone.types.makeAppStateDefault(app.cfg);
            app.tuneDB   = capstone.data.TuneDB_default(app.cfg);
            
            app.frameBuf = capstone.util.frameBufferInit(500);
            app.runLog   = capstone.types.makeRunLog("run_" + string(datetime("now","Format","yyyyMMdd_HHmmss")), app.cfg);
            
            app.steerCtrlState = [];
            app.ctrlReg = capstone.control.ControllerRegistry();

        
            app.runLog = [];
            app.frameBuf = [];
        
            app.stopFlag = true;
            app.t0 = uint64(0);
        
            % ===== Create Network object =====
            app.net = capstone.io.Network();
        
            disp("[monitor] startup OK");
        end

        % Start timer
        function startTimer(app)
            if isempty(app.tmr) || ~isvalid(app.tmr)
                app.tmr = timer( ...
                    "ExecutionMode","fixedSpacing", ...
                    "Period", 0.05, ...       % 20 Hz giả lập
                    "TimerFcn", @(~,~)app.tick() );
            end
            start(app.tmr);
        end

        % Stop timer
        function stopTimer(app)
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
            end
        end
        
        % Timer interrupt
        function tick(app)
            if app.stopFlag || app.t0 == 0
                disp(strcat("[monitor]: Stop flag ",string(app.stopFlag)));
                disp(strcat("[monitor]: time",string(app.t0)));
                return;
            end
            if isempty(app.net) || ~app.net.isConnected()
                disp(strcat("[monitor]: Link ",string(app.net)));
                disp(strcat("[monitor]: Connection ",string(app.net.isConnected())));
                return;
            end
        
            [ok, meas] = app.net.recvSensorFrame(0.2);
            if ~ok
                disp("Invalid frame")
                return;
            end
        
            [lineNorm, mask] = capstone.signal.normalizeLine( ...
                meas.lineRaw, app.cfg.line.offset, app.cfg.line.scale, app.cfg.line.threshold);
        
            e = capstone.signal.computeError(lineNorm, mask, app.cfg.line.positions);
        
            % runtime frame
            rt = capstone.type.makeRuntimeFrame();
            rt.t = toc(app.t0);
            rt.meas = meas;
            rt.proc.lineNorm = lineNorm;
            rt.proc.mask = mask;
            rt.proc.error = e;
        
            % buffer + log
            app.frameBuf = capstone.util.frameBufferPush(app.frameBuf, rt);
            app.runLog.frames = [app.runLog.frames; rt]; %#ok<AGROW>
        
            % plot
            plot(app.ErrorAxes, rt.t, rt.proc.error, '.b'); hold(app.ErrorAxes,"on");
            bar(app.LineAxes, rt.proc.lineNorm); ylim(app.LineAxes,[0 1]);
        
            drawnow limitrate;
            disp("[monitor] tick");

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startup();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % sửa thông số theo bạn
            btName = "Behind the scream122";
            ch = 1;

    app.net.connectBluetooth(btName, ch);
            disp("[monitor] Connect pressed");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disp("[monitor] Start pressed");
        
            if isempty(app.net) || ~app.net.isConnected()
                disp("[monitor] Not connected");
                return;
            end
        
            app.stopFlag = false;
            app.t0 = tic;
        
            % Enter OPERATION
            app.net.sendByte(app.cfg.proto.CMD_MODE_OP);
            app.net.waitAck(app.cfg.timing.rxTimeoutS);
        
            app.startTimer();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp("[monitor] Stop pressed");
        
            app.stopFlag = true;
            app.stopTimer();
        
            if ~isempty(app.net) && app.net.isConnected()
                app.net.sendControl(uint8(0), app.cfg.steer.centerU16, app.cfg.proto.CMD_STOP);
                app.net.sendByte(app.cfg.proto.CMD_MODE_IDLE);
            end
        
            app.t0 = uint64(0);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ErrorAxes = uiaxes(app.UIFigure);
            title(app.ErrorAxes, 'Error')
            xlabel(app.ErrorAxes, 'X')
            ylabel(app.ErrorAxes, 'Y')
            zlabel(app.ErrorAxes, 'Z')
            app.LineAxes = uiaxes(app.UIFigure);
            title(app.LineAxes, 'Line')
            xlabel(app.LineAxes, 'X')
            ylabel(app.LineAxes, 'Y')
            zlabel(app.LineAxes, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunModeDropDownLabel = uilabel(app.UIFigure);
            app.RunModeDropDownLabel.Text = 'Run Mode';
            app.RunModeDropDown = uidropdown(app.UIFigure);
            app.RunModeDropDown.Items = {'Auto', 'Manual', 'Stop'};
            app.RunModeDropDown.Value = 'Auto';
            app.ControllerDropDownLabel = uilabel(app.UIFigure);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.UIFigure);
            app.ControllerDropDown.Items = {'PID', 'ONOFF'};
            app.ControllerDropDown.Value = 'PID';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end